classdef LeaderBoard < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure          matlab.ui.Figure
        Label             matlab.ui.control.Label
        GridLayout        matlab.ui.container.GridLayout
        TabGroup          matlab.ui.container.TabGroup
        StudentsTab       matlab.ui.container.Tab
        GridLayout2       matlab.ui.container.GridLayout
        AccuracyLabel     matlab.ui.control.Label
        UniversityLabel   matlab.ui.control.Label
        NameLabel         matlab.ui.control.Label
        Label_2           matlab.ui.control.Label
        UITable           matlab.ui.control.Table
        UniversitiesTab   matlab.ui.container.Tab
        GridLayout3       matlab.ui.container.GridLayout
        UIAxes            matlab.ui.control.UIAxes
        TextArea          matlab.ui.control.TextArea
        Image2            matlab.ui.control.Image
        EditField         matlab.ui.control.NumericEditField
        EditFieldLabel    matlab.ui.control.Label
        LeaderboardLabel  matlab.ui.control.Label
        StopButton        matlab.ui.control.Button
        StartButton       matlab.ui.control.Button
        Image3            matlab.ui.control.Image
    end

    
    methods (Access = public)
        
        function synchronize(app, obj, event)
                %T = struct2table(dir);
                %out=string(T.name); %convert everything to a string
                previousstep=app.d;
                app.d=datetime(now(),'ConvertFrom','datenum')+hours(2);
                %TODO: the channel ID and the ReadKey have to be adjusted according to the actual ThinkSpeak Channel used.
                T=thingSpeakRead(2251224, ReadKey='BDCUA8ZQLQINBKNU', OutputFormat='table', DateRange=[previousstep,app.d]);
                %TODO: depending on the information from the ThinkSpeak channel the following section has to be adjusted.
                % - take into account additional fields
                % - input validation for prevention of cheating
                
                for ind=1:height(T)
                            %load(out(ind))
                            sub=table(T.Participant(ind), T.UniName(ind), T.Accuracy(ind));
                            sub.Properties.VariableNames=["Name", "University", "Accuracy"];
                            if nnz(sub.Name==app.UITable.Data.Name)>0 || isblocked(sub.Name) % we already have a submission from this participant
                            % check that the new accuracy is higher before appending
                               if double(app.UITable.Data.Accuracy(sub.Name==app.UITable.Data.Name)) < double(sub.Accuracy)
                                   app.UITable.Data(sub.Name==app.UITable.Data.Name,2:end)=sub; %eliminate previous submission
                                   %app.UITable.Data(height(app.UITable.Data.Name)+1,2:end)=sub;
                               end
                            else 
                               app.UITable.Data(height(app.UITable.Data.Name)+1,2:end)=sub; %append new submission
                            end
                            clear sub
                            app.UITable.Data=sortrows(app.UITable.Data,4,'descend');
                            for idx=1:height(app.UITable.Data)
                                app.UITable.Data.Rank(idx)=idx;
                            end
                end
                pause(1);
                
                %Add a university ranking to the leaderboard
                %the following vectors are sorted already
                ETH=app.UITable.Data{app.UITable.Data.University=="ETHZ","Accuracy"};
                EPFL=app.UITable.Data{app.UITable.Data.University=="EPFL","Accuracy"};
                UniBern=app.UITable.Data{app.UITable.Data.University=="UniBern","Accuracy"};

                ETHn=zeros(1,5);
                if length(ETH)>5
                    ETHn=ETH(1:5);
                else
                    ETHn(1:length(ETH))=ETH;
                end

                EPFLn=zeros(1,5);
                 if length(EPFL)>5
                    EPFLn=EPFL(1:5);
                else
                    EPFLn(1:length(EPFL))=EPFL;
                end

                UniBernn=zeros(1,5);
                 if length(UniBern)>5
                    UniBernn=UniBern(1:5);
                else
                    UniBernn(1:length(UniBern))=UniBern;
                end
                
                weights=1:-0.2:0.2;
                x(1)=weights*ETHn';
                x(2)=weights*EPFLn';
                x(3)=weights*UniBernn';
                
                labls={"\color{white} ETHZ","\color{white} EPFL","\color{white} Uni Bern"};
                [~,ind]=sort(x);
                barh(app.UIAxes,x(ind));
                yticklabels(app.UIAxes,labls(ind));
                app.UIAxes.YAxis.FontSize =20;
                xticks(app.UIAxes,[0:0.5:3]);
                app.UIAxes.XLim=[0 3];
                xticklabels(app.UIAxes,{"\color{white} 0", "\color{white} 0.5", "\color{white} 1", "\color{white} 1.5", "\color{white} 2", "\color{white} 2.5", "\color{white} 3"});
                title(app.UIAxes,"Inter University Ranking",FontSize=24,Color=[1 1 1]);
                ylabel(app.UIAxes,"");
                xlabel(app.UIAxes,"Score",FontSize=16,Color=[1 1 1]);
        end
    end

    properties (Access = private)
        %TODO: change the first argument to the start date of the hackathon
        d = datetime(2023,9,26,15,0,0);
        t = timer % timer
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.UITable.Data=cell2table({1, "", "", 1});
            app.UITable.Data(1,:)=[];
            app.UITable.Data.Properties.VariableNames=["Rank", "Name", "University","Accuracy"];
            %cd ../AMLD2022_Submissions/

        end

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
            app.t=timer('TimerFcn',@app.synchronize,'ExecutionMode','fixedSpacing','Period',1,'StartDelay',1); %Property % Description
            start(app.t);
            %synchronize(app);
        end

        % Button pushed function: StopButton
        function StopButtonPushed(app, event)
            tab=app.UITable.Data;
            stop(app.t)
            %delete(app.t)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.Image3 = uiimage(app.GridLayout);
            app.Image3.ImageSource = fullfile(pathToMLAPP, 'matlab-pattern-desktop-wallpaper2b.jpg');
            app.StartButton = uibutton(app.GridLayout, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = 'Start';
            app.StopButton = uibutton(app.GridLayout, 'push');
            app.StopButton.ButtonPushedFcn = createCallbackFcn(app, @StopButtonPushed, true);
            app.StopButton.Text = 'Stop';
            app.LeaderboardLabel = uilabel(app.GridLayout);
            app.LeaderboardLabel.Text = 'Leaderboard';
            app.EditFieldLabel = uilabel(app.GridLayout);
            app.EditFieldLabel.Visible = 'off';
            app.EditFieldLabel.Text = 'Edit Field';
            app.EditField = uieditfield(app.GridLayout, 'numeric');
            app.EditField.Visible = 'off';
            app.Image2 = uiimage(app.GridLayout);
            app.Image2.ImageSource = fullfile(pathToMLAPP, 'mathworks-logo-full-color-rgb-reversed.png');
            app.TextArea = uitextarea(app.GridLayout);
            app.TabGroup = uitabgroup(app.GridLayout);
            app.StudentsTab = uitab(app.TabGroup);
            app.StudentsTab.Title = 'Students';
            app.GridLayout2 = uigridlayout(app.StudentsTab);
            app.UITable = uitable(app.GridLayout2);
            app.UITable.ColumnName = {'Rank'; 'Name'; 'University'; 'Accuracy'};
            app.UITable.RowName = {};
            app.Label_2 = uilabel(app.GridLayout2);
            app.Label_2.Text = ' #';
            app.NameLabel = uilabel(app.GridLayout2);
            app.NameLabel.Text = 'Name';
            app.UniversityLabel = uilabel(app.GridLayout2);
            app.UniversityLabel.Text = 'University';
            app.AccuracyLabel = uilabel(app.GridLayout2);
            app.AccuracyLabel.Text = 'Accuracy';
            app.UniversitiesTab = uitab(app.TabGroup);
            app.UniversitiesTab.Title = 'Universities';
            app.GridLayout3 = uigridlayout(app.UniversitiesTab);
            app.UIAxes = uiaxes(app.GridLayout3);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.Label = uilabel(app.UIFigure);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = LeaderBoard

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end